classdef Humanoid_Hand < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        GetTrajectoryButton      matlab.ui.control.Button
        TrajectoryLabel          matlab.ui.control.Label
        ZEditField               matlab.ui.control.NumericEditField
        ZEditFieldLabel          matlab.ui.control.Label
        YEditField               matlab.ui.control.NumericEditField
        YEditFieldLabel          matlab.ui.control.Label
        XEditField               matlab.ui.control.NumericEditField
        XEditFieldLabel          matlab.ui.control.Label
        OperationalSpaceLabel    matlab.ui.control.Label
        PinkyButton_2            matlab.ui.control.Button
        RingButton_2             matlab.ui.control.Button
        MiddleButton_2           matlab.ui.control.Button
        IndexButton_2            matlab.ui.control.Button
        ThumbButton_2            matlab.ui.control.Button
        PositionLabel            matlab.ui.control.Label
        SelectFingerButtonGroup  matlab.ui.container.ButtonGroup
        PinkyButton              matlab.ui.control.RadioButton
        RingButton               matlab.ui.control.RadioButton
        MiddleButton             matlab.ui.control.RadioButton
        IndexButton              matlab.ui.control.RadioButton
        ThumbButton              matlab.ui.control.RadioButton
        ThumbsupButton           matlab.ui.control.Button
        PeaceButton              matlab.ui.control.Button
        ASUButton                matlab.ui.control.Button
        HumanoidHandLabel        matlab.ui.control.Label
        SignLibraryLabel         matlab.ui.control.Label
        SimulateButton           matlab.ui.control.Button
        PinkyLabel               matlab.ui.control.Label
        RingLabel                matlab.ui.control.Label
        MiddleLabel              matlab.ui.control.Label
        IndexLabel               matlab.ui.control.Label
        ThumbLabel               matlab.ui.control.Label
        Q20EditField             matlab.ui.control.NumericEditField
        Q20EditFieldLabel        matlab.ui.control.Label
        Q19EditField             matlab.ui.control.NumericEditField
        Q19EditFieldLabel        matlab.ui.control.Label
        Q18EditField             matlab.ui.control.NumericEditField
        Q18EditFieldLabel        matlab.ui.control.Label
        Q17EditField             matlab.ui.control.NumericEditField
        Q17EditFieldLabel        matlab.ui.control.Label
        Q16EditField             matlab.ui.control.NumericEditField
        Q16EditFieldLabel        matlab.ui.control.Label
        Q15EditField             matlab.ui.control.NumericEditField
        Q15EditFieldLabel        matlab.ui.control.Label
        Q14EditField             matlab.ui.control.NumericEditField
        Q14EditFieldLabel        matlab.ui.control.Label
        Q13EditField             matlab.ui.control.NumericEditField
        Q13EditFieldLabel        matlab.ui.control.Label
        Q12EditField             matlab.ui.control.NumericEditField
        Q12EditFieldLabel        matlab.ui.control.Label
        Q11EditField             matlab.ui.control.NumericEditField
        Q11EditFieldLabel        matlab.ui.control.Label
        Q10EditField             matlab.ui.control.NumericEditField
        Q10EditFieldLabel        matlab.ui.control.Label
        Q9EditField              matlab.ui.control.NumericEditField
        Q9EditFieldLabel         matlab.ui.control.Label
        Q8EditField              matlab.ui.control.NumericEditField
        Q8EditFieldLabel         matlab.ui.control.Label
        Q7EditField              matlab.ui.control.NumericEditField
        Q7EditFieldLabel         matlab.ui.control.Label
        Q6EditField              matlab.ui.control.NumericEditField
        Q6EditFieldLabel         matlab.ui.control.Label
        Q5EditField              matlab.ui.control.NumericEditField
        Q5EditFieldLabel         matlab.ui.control.Label
        Q4EditField              matlab.ui.control.NumericEditField
        Q4EditFieldLabel         matlab.ui.control.Label
        Q3EditField              matlab.ui.control.NumericEditField
        Q3EditFieldLabel         matlab.ui.control.Label
        Q2EditField              matlab.ui.control.NumericEditField
        Q2EditFieldLabel         matlab.ui.control.Label
        Q1EditField              matlab.ui.control.NumericEditField
        Q1EditFieldLabel         matlab.ui.control.Label
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: SimulateButton
        function SimulateButtonPushed(app, event)
            q1 = app.Q1EditField.Value;
            q2 = app.Q2EditField.Value;
            q3 = app.Q3EditField.Value;
            q4 = app.Q4EditField.Value;
            
            q5 = app.Q5EditField.Value;
            q6 = app.Q6EditField.Value;
            q7 = app.Q7EditField.Value;
            q8 = app.Q8EditField.Value;

            q9 = app.Q9EditField.Value;
            q10 = app.Q10EditField.Value;
            q11 = app.Q11EditField.Value;
            q12 = app.Q12EditField.Value;

            q13 = app.Q13EditField.Value;
            q14 = app.Q14EditField.Value;
            q15 = app.Q15EditField.Value;
            q16 = app.Q16EditField.Value;

            q17 = app.Q17EditField.Value;
            q18 = app.Q18EditField.Value;
            q19 = app.Q19EditField.Value;
            q20 = app.Q20EditField.Value;


            hand = SGparadigmatic;

            qm = [q1 q2 q3 q4 q5 q6 q7 q8 q9 q10 q11 q12 q13 q14 q15 q16 q17 q18 q19 q20];


            set(0,'Units','pixels') 
            scnsize = get(0,'ScreenSize');
            fig1 = figure(1);


            position = get(fig1,'Position');
            outerpos = get(fig1,'OuterPosition');
            borders = outerpos - position;
            edge = -borders(1)/2;
            pos1 = [edge,edge,scnsize(3)/2,scnsize(4)+edge];
    

            % plotting hands
            figure(1)
            SGplotHand(hand);
            axis on
            grid on
            view([-154 12]);
            axis([-70 80 20 180 -80 100]);

            for j = 1:20
                qq(j,1:20) = linspace(0.000001,qm(j),20);
            end

            qmm = zeros(20,20);
            for i = 1:20

                qmm(1:20,i) = qq(1:20,i);

                figure(1)
                hand1 = SGmoveHand(hand,qmm(1:20,i));
                SGplotHand(hand1);
                axis on
                grid on
                view([-154 -120 100]);
                axis([-70 80 20 180 -80 100]);
                
            end
        end

        % Button pushed function: ASUButton
        function ASUButtonPushed(app, event)
            q1 = -1.001;
            q2 = 0.991;
            q3 = 0.001;
            q4 = 0.001;
            
            q5 = 0.201;
            q6 = 0.001;
            q7 = 0.001;
            q8 = 0.001;

            q9 = -0.201;
            q10 = 0.991;
            q11 = 1.991;
            q12 = 1.001;

            q13 = 0;
            q14 = 0;
            q15 = 0;
            q16 = 0;

            q17 = -0.301;
            q18 = 0;
            q19 = 0;
            q20 = 0;


            hand = SGparadigmatic;

            qm = [q1 q2 q3 q4 q5 q6 q7 q8 q9 q10 q11 q12 q13 q14 q15 q16 q17 q18 q19 q20];


            set(0,'Units','pixels') 
            scnsize = get(0,'ScreenSize');
            fig1 = figure(1);


            position = get(fig1,'Position');
            outerpos = get(fig1,'OuterPosition');
            borders = outerpos - position;
            edge = -borders(1)/2;
            pos1 = [edge,edge,scnsize(3)/2,scnsize(4)+edge];
    

            % plotting hands
            figure(1)
            SGplotHand(hand);
            axis on
            grid on
            view([-154 12]);
            axis([-70 80 20 180 -80 100]);

            for j = 1:20
                qq(j,1:20) = linspace(0.000001,qm(j),20);
            end

            qmm = zeros(20,20);
            for i = 1:20

                qmm(1:20,i) = qq(1:20,i);

                figure(1)
                hand1 = SGmoveHand(hand,qmm(1:20,i));
                SGplotHand(hand1);
                axis on
                grid on
                view([-154 -120 100]);
                axis([-70 80 20 180 -80 100]);

            end
        end

        % Button pushed function: PeaceButton
        function PeaceButtonPushed(app, event)
            q1 = -1.001;
            q2 = 0.501;
            q3 = 0.001;
            q4 = 0.001;
            
            q5 = 0.201;
            q6 = 0.001;
            q7 = 0.001;
            q8 = 0.001;

            q9 = -0.401;
            q10 = 0.001;
            q11 = 0.001;
            q12 = 0.001;

            q13 = -0.301;
            q14 = 0.991;
            q15 = 1.991;
            q16 = 1.001;

            q17 = -0.401;
            q18 = 0.991;
            q19 = 1.991;
            q20 = 1.001;


            hand = SGparadigmatic;

            qm = [q1 q2 q3 q4 q5 q6 q7 q8 q9 q10 q11 q12 q13 q14 q15 q16 q17 q18 q19 q20];


            set(0,'Units','pixels') 
            scnsize = get(0,'ScreenSize');
            fig1 = figure(1);


            position = get(fig1,'Position');
            outerpos = get(fig1,'OuterPosition');
            borders = outerpos - position;
            edge = -borders(1)/2;
            pos1 = [edge,edge,scnsize(3)/2,scnsize(4)+edge];
    

            % plotting hands
            figure(1)
            SGplotHand(hand);
            axis on
            grid on
            view([-154 12]);
            axis([-70 80 20 180 -80 100]);

            for j = 1:20
                qq(j,1:20) = linspace(0.000001,qm(j),20);
            end

            qmm = zeros(20,20);
            for i = 1:20

                qmm(1:20,i) = qq(1:20,i);

                figure(1)
                hand1 = SGmoveHand(hand,qmm(1:20,i));
                SGplotHand(hand1);
                axis on
                grid on
                view([-154 -120 100]);
                axis([-70 80 20 180 -80 100]);

            end
        end

        % Button pushed function: ThumbsupButton
        function ThumbsupButtonPushed(app, event)
            q1 = 1.001;
            q2 = 0.501;
            q3 = 0.001;
            q4 = 0.001;
            
            q5 = 0.001;
            q6 = 0.991;
            q7 = 1.991;
            q8 = 1.001;

            q9 = 0.001;
            q10 = 0.991;
            q11 = 1.991;
            q12 = 1.001;

            q13 = 0.001;
            q14 = 0.991;
            q15 = 1.991;
            q16 = 1.001;

            q17 = 0.001;
            q18 = 0.991;
            q19 = 1.991;
            q20 = 1.001;


            hand = SGparadigmatic;

            qm = [q1 q2 q3 q4 q5 q6 q7 q8 q9 q10 q11 q12 q13 q14 q15 q16 q17 q18 q19 q20];


            set(0,'Units','pixels') 
            scnsize = get(0,'ScreenSize');
            fig1 = figure(1);


            position = get(fig1,'Position');
            outerpos = get(fig1,'OuterPosition');
            borders = outerpos - position;
            edge = -borders(1)/2;
            pos1 = [edge,edge,scnsize(3)/2,scnsize(4)+edge];
    

            % plotting hands
            figure(1)
            SGplotHand(hand);
            axis on
            grid on
            view([-154 12]);
            axis([-70 80 20 180 -80 100]);

            for j = 1:20
                qq(j,1:20) = linspace(0.000001,qm(j),20);
            end

            qmm = zeros(20,20);
            for i = 1:20

                qmm(1:20,i) = qq(1:20,i);

                figure(1)
                hand1 = SGmoveHand(hand,qmm(1:20,i));
                SGplotHand(hand1);
                axis on
                grid on
                view([-154 -120 100]);
                axis([-70 80 20 180 -80 100]);

            end
        end

        % Selection changed function: SelectFingerButtonGroup
        function SelectFingerButtonGroupSelectionChanged(app, event)
            selectedButton = app.SelectFingerButtonGroup.SelectedObject;
            q1 = app.Q1EditField.Value;
            q2 = app.Q2EditField.Value;
            q3 = app.Q3EditField.Value;
            q4 = app.Q4EditField.Value;
            
            q5 = app.Q5EditField.Value;
            q6 = app.Q6EditField.Value;
            q7 = app.Q7EditField.Value;
            q8 = app.Q8EditField.Value;

            q9 = app.Q9EditField.Value;
            q10 = app.Q10EditField.Value;
            q11 = app.Q11EditField.Value;
            q12 = app.Q12EditField.Value;

            q13 = app.Q13EditField.Value;
            q14 = app.Q14EditField.Value;
            q15 = app.Q15EditField.Value;
            q16 = app.Q16EditField.Value;

            q17 = app.Q17EditField.Value;
            q18 = app.Q18EditField.Value;
            q19 = app.Q19EditField.Value;
            q20 = app.Q20EditField.Value;
            qm = [q1 q2 q3 q4 q5 q6 q7 q8 q9 q10 q11 q12 q13 q14 q15 q16 q17 q18 q19 q20]';
            for j = 1:20
            qq(j,1:20) = linspace(0.000001,qm(j),20);
            end
            for i = 1:20

                qmm(1:20,i) = qq(1:20,i);
            end


            q(1:20,1:20) = qmm(1:20,1:20);
             
           [x1,y1,z1] = DK(q(1,20),q(2,20),q(3,20),q(4,20),1);
           [x2,y2,z2] = DK(q(5,20),q(6,20),q(7,20),q(8,20),2);
           [x3,y3,z3] = DK(q(9,20),q(10,20),q(11,20),q(12,20),3);
           [x4,y4,z4] = DK(q(13,20),q(14,20),q(15,20),q(16,20),4);
           [x5,y5,z5] = DK(q(17,20),q(18,20),q(19,20),q(20,20),5);

              xt = x1(1,20);
              yt = y1(1,20);
               zt = z1(1,20);
 
              xi = x2(1,20);
              yi = y2(1,20);
              zi = z2(1,20);
 
              xm = x3(1,20);
              ym = y3(1,20);
              zm = z3(1,20);
 
              xr = x4(1,20);
              yr = y4(1,20);
              zr = z4(1,20);
 
              xp = x5(1,20);
              yp = y5(1,20);
              zp = z5(1,20);

            if app.ThumbButton.Value == 1
                app.XEditField.Value = xt
                app.YEditField.Value = yt
                app.ZEditField.Value = zt
            elseif app.IndexButton.Value == 1
                app.XEditField.Value = xi
                app.YEditField.Value = yi
                app.ZEditField.Value = zi
            elseif app.MiddleButton.Value == 1
                app.XEditField.Value = xm
                app.YEditField.Value = ym
                app.ZEditField.Value = zm
            elseif app.RingButton.Value == 1
                app.XEditField.Value = xr
                app.YEditField.Value = yr
                app.ZEditField.Value = zr
            elseif app.PinkyButton.Value == 1
                app.XEditField.Value = xp
                app.YEditField.Value = yp
                app.ZEditField.Value = zp
            end
        end

        % Button pushed function: GetTrajectoryButton
        function GetTrajectoryButtonPushed(app, event)
            hand = SGparadigmatic;
            q1 = app.Q1EditField.Value;
            q2 = app.Q2EditField.Value;
            q3 = app.Q3EditField.Value;
            q4 = app.Q4EditField.Value;
            
            q5 = app.Q5EditField.Value;
            q6 = app.Q6EditField.Value;
            q7 = app.Q7EditField.Value;
            q8 = app.Q8EditField.Value;

            q9 = app.Q9EditField.Value;
            q10 = app.Q10EditField.Value;
            q11 = app.Q11EditField.Value;
            q12 = app.Q12EditField.Value;

            q13 = app.Q13EditField.Value;
            q14 = app.Q14EditField.Value;
            q15 = app.Q15EditField.Value;
            q16 = app.Q16EditField.Value;

            q17 = app.Q17EditField.Value;
            q18 = app.Q18EditField.Value;
            q19 = app.Q19EditField.Value;
            q20 = app.Q20EditField.Value;

            qm = [q1 q2 q3 q4 q5 q6 q7 q8 q9 q10 q11 q12 q13 q14 q15 q16 q17 q18 q19 q20]';


            set(0,'Units','pixels') 
            scnsize = get(0,'ScreenSize');
            fig1 = figure(1);


            position = get(fig1,'Position');
            outerpos = get(fig1,'OuterPosition');
            borders = outerpos - position;
            edge = -borders(1)/2;
            pos1 = [edge,edge,scnsize(3)/2,scnsize(4)+edge];


            % plotting hands
            figure(1)
            SGplotHand(hand);
            axis on
            grid on
            view([-154 12]);
            axis([-70 80 20 180 -80 100]);

            for j = 1:20
                qq(j,1:20) = linspace(0.000001,qm(j),20);
            end

            qmm = zeros(20,20);

            % to plot the hand movemnt 
            for i = 1:20

                qmm(1:20,i) = qq(1:20,i);

                figure(1)
                hand1 = SGmoveHand(hand,qmm(1:20,i));
                SGplotHand(hand1);
                axis on
                grid on
                view([-154 -120 100]);
                axis([-70 80 20 180 -80 100]);

            end
             q(1:20,1:20) = qmm(1:20,1:20);
             [x1,y1,z1] = DK(q(1,20),q(2,20),q(3,20),q(4,20),1);
             [x2,y2,z2] = DK(q(5,20),q(6,20),q(7,20),q(8,20),2);
             [x3,y3,z3] = DK(q(9,20),q(10,20),q(11,20),q(12,20),3);
             [x4,y4,z4] = DK(q(13,20),q(14,20),q(15,20),q(16,20),4);
             [x5,y5,z5] = DK(q(17,20),q(18,20),q(19,20),q(20,20),5);
             figure
             hold on
             plot3(x1,y1,z1,'-r','LineWidth',2)
             plot3(x2,y2,z2,'-blue','LineWidth',2)
             plot3(x3,y3,z3,'-r','LineWidth',2)
             plot3(x4,y4,z4,'-blue','LineWidth',2)
             plot3(x5,y5,z5,'-red','LineWidth',2)
             SGplotHand(hand1);
             axis on
             grid on
             view([-154 -120 100]);
             axis([-70 80 20 180 -80 100]);
             hold off
       

        end

        % Button pushed function: ThumbButton_2
        function ThumbButton_2Pushed(app, event)
            hand = SGparadigmatic;               
            t1 = linspace(-1.573,1.573,10);                
            t2 = linspace(-pi/2,0.0872665,10);
            t3 = linspace(-2.1,0,10);
            t4 = linspace(-pi/4,0,10);
                
                                for i =1:10
                                       for j = 1:10
                                           for o = 1:10
                                               for s = 1:10
                 a1 = 25;
                 a2 = 15;
                 a3 = 10;
                 x0 = -37;
                 y0 = 45;
                x = x0+ a1*cos(t2(j))*sin(t1(i)) - a3*sin(t4(s))*(cos(t2(j))*sin(t1(i))*sin(t3(o)) + cos(t3(o))*sin(t1(i))*sin(t2(j))) - a3*cos(t4(s))*(sin(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t2(j))*cos(t3(o))*sin(t1(i))) + a2*cos(t2(j))*cos(t3(o))*sin(t1(i)) - a2*sin(t1(i))*sin(t2(j))*sin(t3(o));
                y = y0+ a1*cos(t1(i))*cos(t2(j)) - a3*cos(t4(s))*(cos(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t1(i))*cos(t2(j))*cos(t3(o))) - a3*sin(t4(s))*(cos(t1(i))*cos(t2(j))*sin(t3(o)) + cos(t1(i))*cos(t3(o))*sin(t2(j))) + a2*cos(t1(i))*cos(t2(j))*cos(t3(o)) - a2*cos(t1(i))*sin(t2(j))*sin(t3(o));
                z = a1*sin(t2(j)) + a3*cos(t4(s))*(cos(t2(j))*sin(t3(o)) + cos(t3(o))*sin(t2(j))) + a3*sin(t4(s))*(cos(t2(j))*cos(t3(o)) - sin(t2(j))*sin(t3(o))) + a2*cos(t2(j))*sin(t3(o)) + a2*cos(t3(o))*sin(t2(j));
                plot3(x,y,z,'r.','MarkerSize',30)
                hold on
                
                
                                               end
                                           end
                                       end
                                end
                                          
                SGplotHand(hand);
                hold off
            
        end

        % Button pushed function: IndexButton_2
        function IndexButton_2Pushed(app, event)
          hand = SGparadigmatic;
          t1 = linspace(-0.523599,0.523599,10);
          t2 = linspace(-pi/2,0.0872665,10);
          t3 = linspace(-2.9,0,10);
          t4 = linspace(-pi/4,0,10);

                for i =1:10
                       for j = 1:10
                           for o = 1:10
                               for s = 1:10
a1 = 37;
a2 = 30;
a3 = 15;

x = -24+ a1*cos(t2(j))*sin(t1(i)) - a3*sin(t4(s))*(cos(t2(j))*sin(t1(i))*sin(t3(o)) + cos(t3(o))*sin(t1(i))*sin(t2(j))) - a3*cos(t4(s))*(sin(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t2(j))*cos(t3(o))*sin(t1(i))) + a2*cos(t2(j))*cos(t3(o))*sin(t1(i)) - a2*sin(t1(i))*sin(t2(j))*sin(t3(o));
y = 73+ a1*cos(t1(i))*cos(t2(j)) - a3*cos(t4(s))*(cos(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t1(i))*cos(t2(j))*cos(t3(o))) - a3*sin(t4(s))*(cos(t1(i))*cos(t2(j))*sin(t3(o)) + cos(t1(i))*cos(t3(o))*sin(t2(j))) + a2*cos(t1(i))*cos(t2(j))*cos(t3(o)) - a2*cos(t1(i))*sin(t2(j))*sin(t3(o));
z = a1*sin(t2(j)) + a3*cos(t4(s))*(cos(t2(j))*sin(t3(o)) + cos(t3(o))*sin(t2(j))) + a3*sin(t4(s))*(cos(t2(j))*cos(t3(o)) - sin(t2(j))*sin(t3(o))) + a2*cos(t2(j))*sin(t3(o)) + a2*cos(t3(o))*sin(t2(j));
plot3(x,y,z,'r.','MarkerSize',30)
hold on


                               end
                           end
                       end
                end
                          
SGplotHand(hand);
hold off

        end

        % Button pushed function: MiddleButton_2
        function MiddleButton_2Pushed(app, event)
           hand = SGparadigmatic;
           t1 = linspace(-0.523599,0.523599,10);
            t2 = linspace(-pi/2,0.0872665,10);
            t3 = linspace(-2.9,0,10);
            t4 = linspace(-pi/4,0,10);

                for i =1:10
                       for j = 1:10
                           for o = 1:10
                               for s = 1:10
    a1 = 40;
    a2 = 35;
    a3 = 17;
    x0 = -8;
    y0 = 73;
x = x0+ a1*cos(t2(j))*sin(t1(i)) - a3*sin(t4(s))*(cos(t2(j))*sin(t1(i))*sin(t3(o)) + cos(t3(o))*sin(t1(i))*sin(t2(j))) - a3*cos(t4(s))*(sin(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t2(j))*cos(t3(o))*sin(t1(i))) + a2*cos(t2(j))*cos(t3(o))*sin(t1(i)) - a2*sin(t1(i))*sin(t2(j))*sin(t3(o));
y = y0+ a1*cos(t1(i))*cos(t2(j)) - a3*cos(t4(s))*(cos(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t1(i))*cos(t2(j))*cos(t3(o))) - a3*sin(t4(s))*(cos(t1(i))*cos(t2(j))*sin(t3(o)) + cos(t1(i))*cos(t3(o))*sin(t2(j))) + a2*cos(t1(i))*cos(t2(j))*cos(t3(o)) - a2*cos(t1(i))*sin(t2(j))*sin(t3(o));
z = a1*sin(t2(j)) + a3*cos(t4(s))*(cos(t2(j))*sin(t3(o)) + cos(t3(o))*sin(t2(j))) + a3*sin(t4(s))*(cos(t2(j))*cos(t3(o)) - sin(t2(j))*sin(t3(o))) + a2*cos(t2(j))*sin(t3(o)) + a2*cos(t3(o))*sin(t2(j));
plot3(x,y,z,'r.','MarkerSize',30)
hold on


                               end
                           end
                       end
                end
                          
SGplotHand(hand);
hold off
        end

        % Button pushed function: RingButton_2
        function RingButton_2Pushed(app, event)
            hand = SGparadigmatic;
            t1 = linspace(-0.523599,0.523599,10);
            t2 = linspace(-pi/2,0.0872665,10);
            t3 = linspace(-2.9,0,10);
            t4 = linspace(-pi/4,0,10);

                for i =1:10
                       for j = 1:10
                           for o = 1:10
                               for s = 1:10

    a1 = 37;
    a2 = 30;
    a3 = 15; 
    x0 = 7;
    y0 = 73;

x = x0+ a1*cos(t2(j))*sin(t1(i)) - a3*sin(t4(s))*(cos(t2(j))*sin(t1(i))*sin(t3(o)) + cos(t3(o))*sin(t1(i))*sin(t2(j))) - a3*cos(t4(s))*(sin(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t2(j))*cos(t3(o))*sin(t1(i))) + a2*cos(t2(j))*cos(t3(o))*sin(t1(i)) - a2*sin(t1(i))*sin(t2(j))*sin(t3(o));
y = y0+ a1*cos(t1(i))*cos(t2(j)) - a3*cos(t4(s))*(cos(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t1(i))*cos(t2(j))*cos(t3(o))) - a3*sin(t4(s))*(cos(t1(i))*cos(t2(j))*sin(t3(o)) + cos(t1(i))*cos(t3(o))*sin(t2(j))) + a2*cos(t1(i))*cos(t2(j))*cos(t3(o)) - a2*cos(t1(i))*sin(t2(j))*sin(t3(o));
z = a1*sin(t2(j)) + a3*cos(t4(s))*(cos(t2(j))*sin(t3(o)) + cos(t3(o))*sin(t2(j))) + a3*sin(t4(s))*(cos(t2(j))*cos(t3(o)) - sin(t2(j))*sin(t3(o))) + a2*cos(t2(j))*sin(t3(o)) + a2*cos(t3(o))*sin(t2(j));
plot3(x,y,z,'r.','MarkerSize',30)
hold on


                               end
                           end
                       end
                end
                          
SGplotHand(hand);
hold off

        end

        % Button pushed function: PinkyButton_2
        function PinkyButton_2Pushed(app, event)
            hand = SGparadigmatic;
            t1 = linspace(-0.523599,0.523599,10);
            t2 = linspace(-pi/2,0.0872665,10);
            t3 = linspace(-2.9,0,10);
            t4 = linspace(-pi/4,0,10);

                for i =1:10
                       for j = 1:10
                           for o = 1:10
                               for s = 1:10

    a1 = 27;
    a2 = 25;
    a3 = 10;
    x0 = 19;
    y0 = 73;


x = x0+ a1*cos(t2(j))*sin(t1(i)) - a3*sin(t4(s))*(cos(t2(j))*sin(t1(i))*sin(t3(o)) + cos(t3(o))*sin(t1(i))*sin(t2(j))) - a3*cos(t4(s))*(sin(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t2(j))*cos(t3(o))*sin(t1(i))) + a2*cos(t2(j))*cos(t3(o))*sin(t1(i)) - a2*sin(t1(i))*sin(t2(j))*sin(t3(o));
y = y0+ a1*cos(t1(i))*cos(t2(j)) - a3*cos(t4(s))*(cos(t1(i))*sin(t2(j))*sin(t3(o)) - cos(t1(i))*cos(t2(j))*cos(t3(o))) - a3*sin(t4(s))*(cos(t1(i))*cos(t2(j))*sin(t3(o)) + cos(t1(i))*cos(t3(o))*sin(t2(j))) + a2*cos(t1(i))*cos(t2(j))*cos(t3(o)) - a2*cos(t1(i))*sin(t2(j))*sin(t3(o));
z = a1*sin(t2(j)) + a3*cos(t4(s))*(cos(t2(j))*sin(t3(o)) + cos(t3(o))*sin(t2(j))) + a3*sin(t4(s))*(cos(t2(j))*cos(t3(o)) - sin(t2(j))*sin(t3(o))) + a2*cos(t2(j))*sin(t3(o)) + a2*cos(t3(o))*sin(t2(j));
plot3(x,y,z,'r.','MarkerSize',30)
hold on


                               end
                           end
                       end
                end
                          
SGplotHand(hand);
hold off
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Q1EditFieldLabel = uilabel(app.UIFigure);
            app.Q1EditFieldLabel.Text = 'Q1';
            app.Q1EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q2EditFieldLabel = uilabel(app.UIFigure);
            app.Q2EditFieldLabel.Text = 'Q2';
            app.Q2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q3EditFieldLabel = uilabel(app.UIFigure);
            app.Q3EditFieldLabel.Text = 'Q3';
            app.Q3EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q4EditFieldLabel = uilabel(app.UIFigure);
            app.Q4EditFieldLabel.Text = 'Q4';
            app.Q4EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q5EditFieldLabel = uilabel(app.UIFigure);
            app.Q5EditFieldLabel.Text = 'Q5';
            app.Q5EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q6EditFieldLabel = uilabel(app.UIFigure);
            app.Q6EditFieldLabel.Text = 'Q6';
            app.Q6EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q7EditFieldLabel = uilabel(app.UIFigure);
            app.Q7EditFieldLabel.Text = 'Q7';
            app.Q7EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q8EditFieldLabel = uilabel(app.UIFigure);
            app.Q8EditFieldLabel.Text = 'Q8';
            app.Q8EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q9EditFieldLabel = uilabel(app.UIFigure);
            app.Q9EditFieldLabel.Text = 'Q9';
            app.Q9EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q10EditFieldLabel = uilabel(app.UIFigure);
            app.Q10EditFieldLabel.Text = 'Q10';
            app.Q10EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q11EditFieldLabel = uilabel(app.UIFigure);
            app.Q11EditFieldLabel.Text = 'Q11';
            app.Q11EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q12EditFieldLabel = uilabel(app.UIFigure);
            app.Q12EditFieldLabel.Text = 'Q12';
            app.Q12EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q13EditFieldLabel = uilabel(app.UIFigure);
            app.Q13EditFieldLabel.Text = 'Q13';
            app.Q13EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q14EditFieldLabel = uilabel(app.UIFigure);
            app.Q14EditFieldLabel.Text = 'Q14';
            app.Q14EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q15EditFieldLabel = uilabel(app.UIFigure);
            app.Q15EditFieldLabel.Text = 'Q15';
            app.Q15EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q16EditFieldLabel = uilabel(app.UIFigure);
            app.Q16EditFieldLabel.Text = 'Q16';
            app.Q16EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q17EditFieldLabel = uilabel(app.UIFigure);
            app.Q17EditFieldLabel.Text = 'Q17';
            app.Q17EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q18EditFieldLabel = uilabel(app.UIFigure);
            app.Q18EditFieldLabel.Text = 'Q18';
            app.Q18EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q19EditFieldLabel = uilabel(app.UIFigure);
            app.Q19EditFieldLabel.Text = 'Q19';
            app.Q19EditField = uieditfield(app.UIFigure, 'numeric');
            app.Q20EditFieldLabel = uilabel(app.UIFigure);
            app.Q20EditFieldLabel.Text = 'Q20';
            app.Q20EditField = uieditfield(app.UIFigure, 'numeric');
            app.ThumbLabel = uilabel(app.UIFigure);
            app.ThumbLabel.Text = 'Thumb';
            app.IndexLabel = uilabel(app.UIFigure);
            app.IndexLabel.Text = 'Index';
            app.MiddleLabel = uilabel(app.UIFigure);
            app.MiddleLabel.Text = 'Middle';
            app.RingLabel = uilabel(app.UIFigure);
            app.RingLabel.Text = 'Ring';
            app.PinkyLabel = uilabel(app.UIFigure);
            app.PinkyLabel.Text = 'Pinky';
            app.SimulateButton = uibutton(app.UIFigure, 'push');
            app.SimulateButton.ButtonPushedFcn = createCallbackFcn(app, @SimulateButtonPushed, true);
            app.SimulateButton.Text = 'Simulate';
            app.SignLibraryLabel = uilabel(app.UIFigure);
            app.SignLibraryLabel.Text = 'Sign Library';
            app.HumanoidHandLabel = uilabel(app.UIFigure);
            app.HumanoidHandLabel.Text = 'Humanoid Hand';
            app.ASUButton = uibutton(app.UIFigure, 'push');
            app.ASUButton.ButtonPushedFcn = createCallbackFcn(app, @ASUButtonPushed, true);
            app.ASUButton.Text = 'ASU';
            app.PeaceButton = uibutton(app.UIFigure, 'push');
            app.PeaceButton.ButtonPushedFcn = createCallbackFcn(app, @PeaceButtonPushed, true);
            app.PeaceButton.Text = 'Peace';
            app.ThumbsupButton = uibutton(app.UIFigure, 'push');
            app.ThumbsupButton.ButtonPushedFcn = createCallbackFcn(app, @ThumbsupButtonPushed, true);
            app.ThumbsupButton.Text = 'Thumbs up';
            app.SelectFingerButtonGroup = uibuttongroup(app.UIFigure);
            app.SelectFingerButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SelectFingerButtonGroupSelectionChanged, true);
            app.SelectFingerButtonGroup.Title = 'Select Finger';
            app.ThumbButton = uiradiobutton(app.SelectFingerButtonGroup);
            app.ThumbButton.Text = 'Thumb';
            app.ThumbButton.Value = true;
            app.IndexButton = uiradiobutton(app.SelectFingerButtonGroup);
            app.IndexButton.Text = 'Index';
            app.MiddleButton = uiradiobutton(app.SelectFingerButtonGroup);
            app.MiddleButton.Text = 'Middle';
            app.RingButton = uiradiobutton(app.SelectFingerButtonGroup);
            app.RingButton.Text = 'Ring';
            app.PinkyButton = uiradiobutton(app.SelectFingerButtonGroup);
            app.PinkyButton.Text = 'Pinky';
            app.PositionLabel = uilabel(app.UIFigure);
            app.PositionLabel.Text = 'Position';
            app.ThumbButton_2 = uibutton(app.UIFigure, 'push');
            app.ThumbButton_2.ButtonPushedFcn = createCallbackFcn(app, @ThumbButton_2Pushed, true);
            app.ThumbButton_2.Text = 'Thumb';
            app.IndexButton_2 = uibutton(app.UIFigure, 'push');
            app.IndexButton_2.ButtonPushedFcn = createCallbackFcn(app, @IndexButton_2Pushed, true);
            app.IndexButton_2.Text = 'Index';
            app.MiddleButton_2 = uibutton(app.UIFigure, 'push');
            app.MiddleButton_2.ButtonPushedFcn = createCallbackFcn(app, @MiddleButton_2Pushed, true);
            app.MiddleButton_2.Text = 'Middle';
            app.RingButton_2 = uibutton(app.UIFigure, 'push');
            app.RingButton_2.ButtonPushedFcn = createCallbackFcn(app, @RingButton_2Pushed, true);
            app.RingButton_2.Text = 'Ring';
            app.PinkyButton_2 = uibutton(app.UIFigure, 'push');
            app.PinkyButton_2.ButtonPushedFcn = createCallbackFcn(app, @PinkyButton_2Pushed, true);
            app.PinkyButton_2.Text = 'Pinky';
            app.OperationalSpaceLabel = uilabel(app.UIFigure);
            app.OperationalSpaceLabel.Text = 'Operational Space';
            app.XEditFieldLabel = uilabel(app.UIFigure);
            app.XEditFieldLabel.Text = 'X';
            app.XEditField = uieditfield(app.UIFigure, 'numeric');
            app.XEditField.Editable = 'off';
            app.YEditFieldLabel = uilabel(app.UIFigure);
            app.YEditFieldLabel.Text = 'Y';
            app.YEditField = uieditfield(app.UIFigure, 'numeric');
            app.YEditField.Editable = 'off';
            app.ZEditFieldLabel = uilabel(app.UIFigure);
            app.ZEditFieldLabel.Text = 'Z';
            app.ZEditField = uieditfield(app.UIFigure, 'numeric');
            app.ZEditField.Editable = 'off';
            app.TrajectoryLabel = uilabel(app.UIFigure);
            app.TrajectoryLabel.Text = 'Trajectory';
            app.GetTrajectoryButton = uibutton(app.UIFigure, 'push');
            app.GetTrajectoryButton.ButtonPushedFcn = createCallbackFcn(app, @GetTrajectoryButtonPushed, true);
            app.GetTrajectoryButton.Text = 'Get Trajectory';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Humanoid_Hand

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end